classdef finalprojectapp1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TempCGauge                    matlab.ui.control.NinetyDegreeGauge
        TempCGaugeLabel               matlab.ui.control.Label
        CoolantlevelinchesGauge       matlab.ui.control.NinetyDegreeGauge
        CoolantlevelinchesGaugeLabel  matlab.ui.control.Label
        BrightlightsLamp              matlab.ui.control.Lamp
        BrightlightsLampLabel         matlab.ui.control.Label
        DimlightsLamp                 matlab.ui.control.Lamp
        DimlightsLampLabel            matlab.ui.control.Label
        DistanceGauge                 matlab.ui.control.LinearGauge
        DistanceGaugeLabel            matlab.ui.control.Label
        MotorspeedGauge               matlab.ui.control.Gauge
        MotorspeedGaugeLabel          matlab.ui.control.Label
        REDLamp                       matlab.ui.control.Lamp
        REDLampLabel                  matlab.ui.control.Label
        YellowLamp                    matlab.ui.control.Lamp
        YellowLampLabel               matlab.ui.control.Label
        GreenLamp                     matlab.ui.control.Lamp
        GreenLampLabel                matlab.ui.control.Label
        DutyCycleEditField            matlab.ui.control.NumericEditField
        DutyCycleEditFieldLabel       matlab.ui.control.Label
        StartButton                   matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            

            arduino=serialport("COM4",9600,"Timeout",15);
            pause(.5)
            
            
            for K=0:1000
            flush(arduino)
            write(arduino,2,'string')
            pause(0.5)
            a=read(arduino,20,'string');
            if not (isa(a,'double'))
                a = split(a) ;
                y=a(1);
                app.DistanceGauge.Value = str2double(y);
                if str2double(y) >=30
                    app.REDLamp.Color = [0 0 0]
                    app.YellowLamp.Color = [0 0 0]
                    app.GreenLamp.Color = [0 0 0]
                
                elseif str2double(y) >=20
                    app.REDLamp.Color = [1 0 0]
                    app.YellowLamp.Color = [0 0 0]
                    app.GreenLamp.Color = [0 0 0]
                
                elseif str2double(y) >=10
                    app.REDLamp.Color = [1 0 0]
                    app.YellowLamp.Color = [1 1 0]
                    app.GreenLamp.Color = [0 0 0]
                else 
                    app.REDLamp.Color = [1 0 0]
                    app.YellowLamp.Color = [1 1 0]
                    app.GreenLamp.Color = [0 1 0]
                
                end
                y1=a(2);
           
                if y1 == "0"
                    app.DimlightsLamp.Color = [0 0 0]
                    app.BrightlightsLamp.Color = [0 0 0]
                end
                if y1 == "1"
                    app.DimlightsLamp.Color = [0 0 0]
                    app.BrightlightsLamp.Color = [1 1 1] 
                end
                if y1 == "2"
                    app.DimlightsLamp.Color = [1 1 1]
                    app.BrightlightsLamp.Color = [0 0 0]
                end
                y2=a(3);
                app.MotorspeedGauge.Value = str2double(y2);
                y3=a(4);
                app.CoolantlevelinchesGauge.Value=str2double(y3)
                
                y4=a(5) ;
                app.TempCGauge.Value = str2double(y4)
                
            end
            flush(arduino)
            
            end
            delete(arduino);
            clear arduino;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DutyCycleEditFieldLabel = uilabel(app.UIFigure);
            app.DutyCycleEditFieldLabel.Text = 'Duty Cycle';
            app.DutyCycleEditField = uieditfield(app.UIFigure, 'numeric');
            app.GreenLampLabel = uilabel(app.UIFigure);
            app.GreenLampLabel.Text = 'Green';
            app.GreenLamp = uilamp(app.UIFigure);
            app.YellowLampLabel = uilabel(app.UIFigure);
            app.YellowLampLabel.Text = 'Yellow';
            app.YellowLamp = uilamp(app.UIFigure);
            app.REDLampLabel = uilabel(app.UIFigure);
            app.REDLampLabel.Text = 'RED';
            app.REDLamp = uilamp(app.UIFigure);
            app.MotorspeedGaugeLabel = uilabel(app.UIFigure);
            app.MotorspeedGaugeLabel.Text = 'Motor speed %';
            app.MotorspeedGauge = uigauge(app.UIFigure, 'circular');
            app.DistanceGaugeLabel = uilabel(app.UIFigure);
            app.DistanceGaugeLabel.Text = 'Distance';
            app.DistanceGauge = uigauge(app.UIFigure, 'linear');
            app.DistanceGauge.Value = 7;
            app.DimlightsLampLabel = uilabel(app.UIFigure);
            app.DimlightsLampLabel.Text = 'Dim lights';
            app.DimlightsLamp = uilamp(app.UIFigure);
            app.BrightlightsLampLabel = uilabel(app.UIFigure);
            app.BrightlightsLampLabel.Text = 'Bright lights';
            app.BrightlightsLamp = uilamp(app.UIFigure);
            app.CoolantlevelinchesGaugeLabel = uilabel(app.UIFigure);
            app.CoolantlevelinchesGaugeLabel.Text = 'Coolant level inches';
            app.CoolantlevelinchesGauge = uigauge(app.UIFigure, 'ninetydegree');
            app.CoolantlevelinchesGauge.Limits = [0 500];
            app.TempCGaugeLabel = uilabel(app.UIFigure);
            app.TempCGaugeLabel.Text = 'Temp C';
            app.TempCGauge = uigauge(app.UIFigure, 'ninetydegree');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalprojectapp1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end