classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        DutyCycleEditField       matlab.ui.control.NumericEditField
        DutyCycleEditFieldLabel  matlab.ui.control.Label
        StartButton              matlab.ui.control.Button
        UIAxes3                  matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        UIAxes                   matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            arduino = serialport("COM4",9600,"Timeout",15);
            pause(1)
            num_points = 255;
            DC = zeros(1,num_points);
            V_res = zeros(1,num_points);
            V_pc = zeros(1,num_points);
            I = zeros(1,num_points);
            R = zeros(1,num_points);
            
            for K = 0:(num_points-1)
                
                flush(arduino)
                write(arduino,2,'string')
                pause(0.5)
                a=read(arduino,4,'string');
                b=read(arduino,6,'string');
                flush(arduino)
                DC(K+1)=a;
                V_res(K+1)=str2double(b);
                V_pc(K+1)=5-V_res(K+1);
                I(K+1)=V_res(K+1)/5000*1000;
                R(K+1)=V_pc(K+1)/I(K+1);
                app.DutyCycleEditField.Value=DC(K+1);
                plot(app.UIAxes,DC(1:(K+1)),V_pc(1:(K+1)),'bo',"MarkerFaceColor",'b')
                plot(app.UIAxes2,DC(1:(K+1)),I(1:(K+1)),'bo',"MarkerFaceColor",'b')
                plot(app.UIAxes3,DC(1:(K+1)),R(1:(K+1)),'bo',"MarkerFaceColor",'b')
            end
            delete(arduino)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'PhotoCell Voltage')
            xlabel(app.UIAxes, 'Duty Cycle %')
            ylabel(app.UIAxes, 'Voltage (V)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Photocell Current')
            xlabel(app.UIAxes2, 'Duty Cycle %')
            ylabel(app.UIAxes2, 'Current (mA)')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Photocell Resistance')
            xlabel(app.UIAxes3, 'Duty Cycle %')
            ylabel(app.UIAxes3, 'Resistance (ohms)')
            zlabel(app.UIAxes3, 'Z')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.DutyCycleEditFieldLabel = uilabel(app.UIFigure);
            app.DutyCycleEditFieldLabel.Text = 'Duty Cycle';
            app.DutyCycleEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end